classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InitializationButton_2     matlab.ui.control.Button
        QuitButton                 matlab.ui.control.Button
        BodyControlPanel           matlab.ui.container.Panel
        ForwardButton              matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        BackwardsButton            matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        MovementControlPanel       matlab.ui.container.Panel
        TurnLeftButton             matlab.ui.control.Button
        BackwardsButton_2          matlab.ui.control.Button
        TurnRightButton            matlab.ui.control.Button
        ForwardButton_2            matlab.ui.control.Button
        QuitButton_2               matlab.ui.control.Button
        ProgrammedGaitTestPanel    matlab.ui.container.Panel
        TetrapodGaitButton         matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        showspeedandbalanceButton  matlab.ui.control.Button
        QuadrupedGaitButton        matlab.ui.control.Button
        WaveGaitButton             matlab.ui.control.Button
        RotateaboutzaxisPanel      matlab.ui.container.Panel
        LeftButton_2               matlab.ui.control.Button
        RightButton_2              matlab.ui.control.Button
        RotateaboutyaxisPanel      matlab.ui.container.Panel
        clockwiseButton            matlab.ui.control.Button
        anticlockwiseButton        matlab.ui.control.Button
        InclinedPlaneTestPanel     matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        s % Description
        distance_b
        distance_movement
    end


    methods (Access = private)

        % Button pushed function: BackwardsButton
        function BackwardsButtonPushed(app, event)
            fprintf(app.s, 2);
            %             app.TextArea.Value=num2str(2);
        end

        % Button pushed function: BackwardsButton_2
        function BackwardsButton_2Pushed(app, event)
            fprintf(app.s, 6);
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            fprintf(app.s, 1);
        end

        % Button pushed function: ForwardButton_2
        function ForwardButton_2Pushed(app, event)
            fprintf(app.s, 5);
        end

        % Button pushed function: InitializationButton_2
        function InitializationButton_2Pushed(app, event)
            delete(instrfindall);
            app.s=serial('/dev/cu.usbmodem1411','BAUD', 9600); % Make sure the baud rate and COM port is
            fopen(app.s);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            fprintf(app.s, 3);
        end

        % Button pushed function: LeftButton_2
        function LeftButton_2Pushed(app, event)
            fprintf(app.s, 11);
        end

        % Button pushed function: QuadrupedGaitButton
        function QuadrupedGaitButtonPushed(app, event)
            fprintf(app.s, 16);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            fclose(app.s);
        end

        % Button pushed function: QuitButton_2
        function QuitButton_2Pushed(app, event)
            fprintf(app.s, 9);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fprintf(app.s, 100);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            fprintf(app.s, 4);
        end

        % Button pushed function: RightButton_2
        function RightButton_2Pushed(app, event)
            fprintf(app.s, 12);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            fprintf(app.s, 33);
            %             app.TextArea.Value=num2str(33);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            fprintf(app.s, 34);
        end

        % Button pushed function: TetrapodGaitButton
        function TetrapodGaitButtonPushed(app, event)
            fprintf(app.s,15);
        end

        % Button pushed function: TurnLeftButton
        function TurnLeftButtonPushed(app, event)
            fprintf(app.s, 7);
        end

        % Button pushed function: TurnRightButton
        function TurnRightButtonPushed(app, event)
            fprintf(app.s, 8);
        end

        % Button pushed function: WaveGaitButton
        function WaveGaitButtonPushed(app, event)
            fprintf(app.s, 17);
        end

        % Button pushed function: anticlockwiseButton
        function anticlockwiseButtonPushed(app, event)
            fprintf(app.s, 14);
        end

        % Button pushed function: clockwiseButton
        function clockwiseButtonPushed(app, event)
            fprintf(app.s, 13);
        end

        % Button pushed function: showspeedandbalanceButton
        function showspeedandbalanceButtonPushed(app, event)
            app.EditField.Value = fscanf(app.s,'%s');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InitializationButton_2 = uibutton(app.UIFigure, 'push');
            app.InitializationButton_2.ButtonPushedFcn = createCallbackFcn(app, @InitializationButton_2Pushed, true);
            app.InitializationButton_2.Text = 'Initialization';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.BodyControlPanel = uipanel(app.UIFigure);
            app.BodyControlPanel.Title = 'Body Control';
            app.ForwardButton = uibutton(app.BodyControlPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.LeftButton = uibutton(app.BodyControlPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.BodyControlPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.BackwardsButton = uibutton(app.BodyControlPanel, 'push');
            app.BackwardsButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardsButtonPushed, true);
            app.BackwardsButton.Text = 'Backwards';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MovementControlPanel = uipanel(app.UIFigure);
            app.MovementControlPanel.Title = 'Movement Control';
            app.TurnLeftButton = uibutton(app.MovementControlPanel, 'push');
            app.TurnLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLeftButtonPushed, true);
            app.TurnLeftButton.Text = 'Turn Left';
            app.BackwardsButton_2 = uibutton(app.MovementControlPanel, 'push');
            app.BackwardsButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackwardsButton_2Pushed, true);
            app.BackwardsButton_2.Text = 'Backwards';
            app.TurnRightButton = uibutton(app.MovementControlPanel, 'push');
            app.TurnRightButton.ButtonPushedFcn = createCallbackFcn(app, @TurnRightButtonPushed, true);
            app.TurnRightButton.Text = 'Turn Right';
            app.ForwardButton_2 = uibutton(app.MovementControlPanel, 'push');
            app.ForwardButton_2.ButtonPushedFcn = createCallbackFcn(app, @ForwardButton_2Pushed, true);
            app.ForwardButton_2.Text = 'Forward';
            app.QuitButton_2 = uibutton(app.MovementControlPanel, 'push');
            app.QuitButton_2.ButtonPushedFcn = createCallbackFcn(app, @QuitButton_2Pushed, true);
            app.QuitButton_2.Text = 'Quit';
            app.ProgrammedGaitTestPanel = uipanel(app.UIFigure);
            app.ProgrammedGaitTestPanel.Title = 'Programmed Gait Test';
            app.TetrapodGaitButton = uibutton(app.ProgrammedGaitTestPanel, 'push');
            app.TetrapodGaitButton.ButtonPushedFcn = createCallbackFcn(app, @TetrapodGaitButtonPushed, true);
            app.TetrapodGaitButton.Text = 'Tetrapod Gait';
            app.EditField = uieditfield(app.ProgrammedGaitTestPanel, 'text');
            app.showspeedandbalanceButton = uibutton(app.ProgrammedGaitTestPanel, 'push');
            app.showspeedandbalanceButton.ButtonPushedFcn = createCallbackFcn(app, @showspeedandbalanceButtonPushed, true);
            app.showspeedandbalanceButton.Text = 'show speed and balance';
            app.QuadrupedGaitButton = uibutton(app.ProgrammedGaitTestPanel, 'push');
            app.QuadrupedGaitButton.ButtonPushedFcn = createCallbackFcn(app, @QuadrupedGaitButtonPushed, true);
            app.QuadrupedGaitButton.Text = 'Quadruped Gait';
            app.WaveGaitButton = uibutton(app.ProgrammedGaitTestPanel, 'push');
            app.WaveGaitButton.ButtonPushedFcn = createCallbackFcn(app, @WaveGaitButtonPushed, true);
            app.WaveGaitButton.Text = 'Wave Gait';
            app.RotateaboutzaxisPanel = uipanel(app.UIFigure);
            app.RotateaboutzaxisPanel.Title = 'Rotate about z axis';
            app.LeftButton_2 = uibutton(app.RotateaboutzaxisPanel, 'push');
            app.LeftButton_2.ButtonPushedFcn = createCallbackFcn(app, @LeftButton_2Pushed, true);
            app.LeftButton_2.Text = 'Left';
            app.RightButton_2 = uibutton(app.RotateaboutzaxisPanel, 'push');
            app.RightButton_2.ButtonPushedFcn = createCallbackFcn(app, @RightButton_2Pushed, true);
            app.RightButton_2.Text = 'Right';
            app.RotateaboutyaxisPanel = uipanel(app.UIFigure);
            app.RotateaboutyaxisPanel.Title = 'Rotate about y axis';
            app.clockwiseButton = uibutton(app.RotateaboutyaxisPanel, 'push');
            app.clockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @clockwiseButtonPushed, true);
            app.clockwiseButton.Text = 'clockwise';
            app.anticlockwiseButton = uibutton(app.RotateaboutyaxisPanel, 'push');
            app.anticlockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @anticlockwiseButtonPushed, true);
            app.anticlockwiseButton.Text = 'anti-clockwise';
            app.InclinedPlaneTestPanel = uipanel(app.UIFigure);
            app.InclinedPlaneTestPanel.Title = 'Inclined Plane Test';
            app.StartButton = uibutton(app.InclinedPlaneTestPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.InclinedPlaneTestPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end